classdef ESM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EinspurmodellUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BremsvorgangTab                 matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        SimulationstartenButton         matlab.ui.control.Button
        StartgeschwindigkeitSliderLabel  matlab.ui.control.Label
        StartgeschwindigkeitSlider      matlab.ui.control.Slider
        kmhLabel                        matlab.ui.control.Label
        Diagramm33BremsvorgangLabel     matlab.ui.control.Label
        StatusBremsLabel                matlab.ui.control.Label
        EinspurmodellTINF15ITALippeMonningerLabel_2  matlab.ui.control.Label
        TangentialkraftTab              matlab.ui.container.Tab
        SimulationButtonTangential      matlab.ui.control.Button
        StatusReadyLabel                matlab.ui.control.Label
        PsiEditFieldLabel               matlab.ui.control.Label
        PsiEditField                    matlab.ui.control.NumericEditField
        ChiEditFieldLabel               matlab.ui.control.Label
        ChiEditField                    matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        Diagramm37TangentialkraftverteilungsdiagrammLabel  matlab.ui.control.Label
        EinspurmodellTINF15ITALippeMonningerLabel_3  matlab.ui.control.Label
        KreisfahrtTab                   matlab.ui.container.Tab
        UIAxesKonstRad                  matlab.ui.control.UIAxes
        UIAxesKonstVel                  matlab.ui.control.UIAxes
        SimulationButtonKreisV          matlab.ui.control.Button
        SimulationButtonKreisR          matlab.ui.control.Button
        RadiusSliderLabel               matlab.ui.control.Label
        RadiusSlider                    matlab.ui.control.Slider
        mLabel_5                        matlab.ui.control.Label
        GeschwindigkeitSliderLabel      matlab.ui.control.Label
        GeschwindigkeitSlider           matlab.ui.control.Slider
        kmhLabel_2                      matlab.ui.control.Label
        Diagramm46KreisfahrtLabel       matlab.ui.control.Label
        StatusKonstVelLabel             matlab.ui.control.Label
        StatusKonstRadLabel             matlab.ui.control.Label
        EinspurmodellTINF15ITALippeMonningerLabel_4  matlab.ui.control.Label
        GierreaktionTab                 matlab.ui.container.Tab
        UIAxesGier                      matlab.ui.control.UIAxes
        Diagramm48GierreaktionLabel     matlab.ui.control.Label
        SimulationButtonGier            matlab.ui.control.Button
        StatusKonstGierLabel            matlab.ui.control.Label
        EinspurmodellTINF15ITALippeMonningerLabel_5  matlab.ui.control.Label
        FahrzeugparameterTab            matlab.ui.container.Tab
        FahrzeuglngelEditFieldLabel     matlab.ui.control.Label
        FahrzeuglngelEditField          matlab.ui.control.NumericEditField
        VorderlngelvEditFieldLabel      matlab.ui.control.Label
        VorderlngelvEditField           matlab.ui.control.NumericEditField
        HinterlngelhEditFieldLabel      matlab.ui.control.Label
        HinterlngelhEditField           matlab.ui.control.NumericEditField
        VerhltnislvlEditFieldLabel      matlab.ui.control.Label
        VerhltnislvlEditField           matlab.ui.control.NumericEditField
        VerhltnislhlEditFieldLabel      matlab.ui.control.Label
        VerhltnislhlEditField           matlab.ui.control.NumericEditField
        CvSternEditFieldLabel           matlab.ui.control.Label
        CvSternEditField                matlab.ui.control.NumericEditField
        ChSternEditFieldLabel           matlab.ui.control.Label
        ChSternEditField                matlab.ui.control.NumericEditField
        SGEditFieldLabel                matlab.ui.control.Label
        SGEditField                     matlab.ui.control.NumericEditField
        EGEditFieldLabel                matlab.ui.control.Label
        EGEditField                     matlab.ui.control.NumericEditField
        hsEditFieldLabel                matlab.ui.control.Label
        hsEditField                     matlab.ui.control.NumericEditField
        ErdbeschleunigunggEditFieldLabel  matlab.ui.control.Label
        ErdbeschleunigunggEditField     matlab.ui.control.NumericEditField
        FahrzeugmassemEditFieldLabel    matlab.ui.control.Label
        FahrzeugmassemEditField         matlab.ui.control.NumericEditField
        ThetaEditFieldLabel             matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        CvEditFieldLabel                matlab.ui.control.Label
        CvEditField                     matlab.ui.control.NumericEditField
        ChEditFieldLabel                matlab.ui.control.Label
        ChEditField                     matlab.ui.control.NumericEditField
        NradLabel                       matlab.ui.control.Label
        NradLabel_2                     matlab.ui.control.Label
        radLabel                        matlab.ui.control.Label
        radLabel_3                      matlab.ui.control.Label
        rads2mLabel                     matlab.ui.control.Label
        rads2mLabel_2                   matlab.ui.control.Label
        FahrzeugparameterLabel          matlab.ui.control.Label
        FahrzeugparameterLabel_2        matlab.ui.control.Label
        FahrzeuggreLabel                matlab.ui.control.Label
        GewichtundKrfteLabel            matlab.ui.control.Label
        FahrzeugverhaltenLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EinspurmodellTINF15ITALippeMonningerLabel_6  matlab.ui.control.Label
        ReifenkennlinienTab             matlab.ui.container.Tab
        ReifenkennlinienartButtonGroup  matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        AsphaltButton                   matlab.ui.control.RadioButton
        KiesloseButton                  matlab.ui.control.RadioButton
        EisButton                       matlab.ui.control.RadioButton
        ReibungmaxEditFieldLabel        matlab.ui.control.Label
        ReibungmaxEditField             matlab.ui.control.NumericEditField
        LngsschlupfmaxEditFieldLabel    matlab.ui.control.Label
        LngsschlupfmaxEditField         matlab.ui.control.NumericEditField
        UIAxes4                         matlab.ui.control.UIAxes
        ReifenkennlinieLabel            matlab.ui.control.Label
        FahrzeugparameterLabel_3        matlab.ui.control.Label
        EinspurmodellTINF15ITALippeMonningerLabel_7  matlab.ui.control.Label
    end


    properties (Access = private)
        
        simRunning = 0 % Description
    end

    methods (Access = private)
    
        function runDiagram33(app)
            app.StatusBremsLabel.Text = "Status: Loading Simulink model...";
            load_system('Laengsdynamik');
            params = get_param('Laengsdynamik','modelworkspace');
            
            app.StatusBremsLabel.Text = "Status: Running simulation...";
            
            l_v = app.VorderlngelvEditField.Value * 1.0;
            h_s = app.hsEditField.Value * 1.0;
            l = app.FahrzeuglngelEditField.Value * 1.0;
            g = app.ErdbeschleunigunggEditField.Value * 1.0;
            v0 = app.StartgeschwindigkeitSlider.Value / 3.6;
            
            [reifenX, reifenY] = getReifenparameter(app,app.ReifenkennlinienartButtonGroup.SelectedObject.Text);
            max_my = max(reifenY);
            params.assignin('reifenY', reifenY);
            params.assignin('reifenX', reifenX);
            params.assignin('l_v',l_v);
            params.assignin('h_s',h_s);
            params.assignin('l',l);
            params.assignin('g',g);
            params.assignin('v0',v0);
            params.assignin('z_vals',[0.0 0.0 0.1 1.0 1.0] * max_my);
            params.assignin('z_time',[0.0 1.5 1.95 2.15 10.0]);
            params.assignin('zv_z',1.0);
            params.assignin('zh_z',1.0);
            params.assignin('useController',1);
            run_time = int32(max([10.0 (4 + v0 / (max_my * g))]));
            run_time = run_time - mod(run_time, 2);
            
            output = sim('Laengsdynamik.slx','Solver','ode4','StopTime',num2str(run_time),'FixedStep','0.01');
            
            a = output.a;
            v = output.v;
            s = output.s;
            hold(app.UIAxes5_2,'off');
            plot(app.UIAxes5_2, a.Time, a.Data, 'LineWidth', 2, 'Color', [0 0 0.3]);
            hold(app.UIAxes6,'off');
            plot(app.UIAxes6, v.Time, v.Data * 3.6, 'LineWidth', 2, 'Color', [1.0 0.5 0.3]);
            hold(app.UIAxes5,'off');
            plot(app.UIAxes5, s.Time, s.Data, 'LineWidth', 2, 'Color', [0.8 0 0]);
            %legend(app.UIAxes2,'a','v','s');
            app.StatusBremsLabel.Text = "Status: Simulation finished";
            close_system('Laengsdynamik',0);
        end
        
        function runDiagram37(app)
            app.StatusReadyLabel.Text = "Status: Loading Simulink model...";
            
            load_system('Laengsdynamik');
            params = get_param('Laengsdynamik','modelworkspace');
            app.StatusReadyLabel.Text = "Status: Running simulation...";
            
            l_v = app.VorderlngelvEditField.Value * 1.0;
            h_s = app.hsEditField.Value * 1.0;
            l = app.FahrzeuglngelEditField.Value * 1.0;
            g = app.ErdbeschleunigunggEditField.Value * 1.0;
            v0 = app.StartgeschwindigkeitSlider.Value / 3.6;
            
            params.assignin('l_v',l_v);
            params.assignin('h_s',h_s);
            params.assignin('l',l);
            params.assignin('g',g);
            params.assignin('v0',v0);
            [reifenX, reifenY] = getReifenparameter(app,app.ReifenkennlinienartButtonGroup.SelectedObject.Text);
            params.assignin('reifenY', reifenY);
            params.assignin('reifenX', reifenX);
            
            psi = l_v / l;
            chi = h_s / l;
            zmin = (-(1-psi)/chi);
            zmax = (psi/chi);
            z_vals = (zmin:0.01:zmax);
            num = size(z_vals);
            z_time = (1:num(2))*0.01;
            FBhMaxVal = psi*psi / (4 * chi);
            FBhMaxFBv = psi * (2 - psi) / (4 * chi);
            FBvMaxVal = - (1 - psi) * (1 - psi) / (4 * chi);
            FBvMaxFBh = - (1 - psi * psi) / (4 * chi);
                    
            params.assignin('z_vals',z_vals);
            params.assignin('z_time',z_time);
            params.assignin('zv_z',1.0);
            params.assignin('zh_z',1.0);
            params.assignin('useController',0);
            
            output = sim('Laengsdynamik.slx','Solver','ode4','StopTime','10.0','FixedStep','0.01');
            
            FBv = output.FBv.Data; 
            FBh = output.FBh.Data;
            
            hold(app.UIAxes2, 'off');
            plot(app.UIAxes2, FBv,FBh, 'LineWidth', 2);
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, [0 0],[(zmin * 1.1) (FBhMaxVal * 1.3)], 'LineWidth', 1.5, 'Color', [0.2 0.2 0.2]);
            plot(app.UIAxes2, [(FBvMaxVal * 1.3) (zmax * 1.1)], [0 0], 'LineWidth', 1.5, 'Color', [0.2 0.2 0.2]);
            plot(app.UIAxes2, [0 FBhMaxFBv FBhMaxFBv],[FBhMaxVal FBhMaxVal 0], 'LineWidth', 1.0, 'Color', [0.5 0.5 0.5]);
            plot(app.UIAxes2, [0 FBvMaxVal FBvMaxVal],[FBvMaxFBh FBvMaxFBh 0], 'LineWidth', 1.0, 'Color', [0.5 0.5 0.5]);
            plot(app.UIAxes2, FBvMaxVal, FBvMaxFBh, '*', 'LineWidth', 3.0, 'Color', [0.0 0.0 0.0]);
            plot(app.UIAxes2, FBhMaxFBv, FBhMaxVal, '*', 'LineWidth', 3.0, 'Color', [0.0 0.0 0.0]);
            grid(app.UIAxes2, 'on');
            %legend(app.UIAxes2, 'Tangentialkraftverteilungsdiagramms');
            app.StatusReadyLabel.Text = "Status: Simulation finished";
            close_system('Laengsdynamik',0);
        end

        function VerhaeltnislvlChanged(app)
            app.PsiEditField.Value = app.VerhltnislvlEditField.Value;
            app.ChEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislvlEditField.Value * app.ChSternEditField.Value);
            app.CvEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value * app.CvSternEditField.Value);
        end
        
        function drawReifenkennlinie(app)
            selectedButton = app.ReifenkennlinienartButtonGroup.SelectedObject.Text;
            [xVals, yVals] = getReifenparameter(app, selectedButton);
            hold(app.UIAxes4, 'off');
            plot(app.UIAxes4, xVals, yVals, 'LineWidth', 2, 'Color', [0 0 0.3]);
            hold(app.UIAxes4, 'on');
            grid(app.UIAxes4, 'on');
            ylim(app.UIAxes4, [0 app.ReibungmaxEditField.Value]);
        end


        function [xVals, yVals] = getReifenparameter(app, name)
            xVals = [];
            yVals = [];
            if name == "Linear"
                xVals = [0.0 app.LngsschlupfmaxEditField.Value 1.0];
                yVals = [0.0 app.ReibungmaxEditField.Value app.ReibungmaxEditField.Value];
            else 
                if name == "Asphalt"
                    xVals = [0 0.05 0.1 0.15 0.2 0.25 (0.2 / app.LngsschlupfmaxEditField.Value)] * (app.LngsschlupfmaxEditField.Value / 0.2);
                    yVals = [0 0.3636 0.8182 0.9545 1.0000 0.9909 0.8182] * app.ReibungmaxEditField.Value;
                else 
                    if name == "Kies lose"
                        xVals = [0 0.05 0.1 0.15 0.2 0.25 ((0.2 / app.LngsschlupfmaxEditField.Value) * 0.9) (0.2 / app.LngsschlupfmaxEditField.Value)] * (app.LngsschlupfmaxEditField.Value / 0.2);
                        yVals = [0 0.2 0.32 0.4 0.45 0.47 0.52 0.55] * app.ReibungmaxEditField.Value;
                    else
                        if name == "Eis"
                            xVals = [0 0.05 0.1 0.15 0.18 0.2 (0.2 / app.LngsschlupfmaxEditField.Value)] * (app.LngsschlupfmaxEditField.Value / 0.2);
                            yVals = [0 0.1 0.15 0.185 0.195 0.2 0.2] * app.ReibungmaxEditField.Value;
                        end
                    end
                end
            end
        end
        
        function disableButtons(app)
            app.SimulationstartenButton.Enable = "Off";
            app.SimulationButtonTangential.Enable = "Off";
            app.SimulationButtonKreisV.Enable = "Off";
            app.SimulationButtonKreisR.Enable = "Off";
            app.SimulationButtonGier.Enable = "Off";
        end
        
        function enableButtons(app)
            app.SimulationstartenButton.Enable = "On";
            app.SimulationButtonTangential.Enable = "On";
            app.SimulationButtonKreisV.Enable = "On";
            app.SimulationButtonKreisR.Enable = "On";
            app.SimulationButtonGier.Enable = "On";
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawReifenkennlinie(app);
            CvEditFieldValueChanged(app,0);
            ChEditFieldValueChanged(app,0);
        end

        % Value changed function: VorderlngelvEditField
        function VorderlngelvEditFieldValueChanged(app, event)
            if app.FahrzeuglngelEditField.Value > 0 
                if app.FahrzeuglngelEditField.Value >= app.VorderlngelvEditField.Value
                    app.HinterlngelhEditField.Value = app.FahrzeuglngelEditField.Value - app.VorderlngelvEditField.Value;
                else
                    app.FahrzeuglngelEditField.Value = app.VorderlngelvEditField.Value;
                    app.HinterlngelhEditField.Value = 0;
                end
                app.VerhltnislvlEditField.Value = app.VorderlngelvEditField.Value / app.FahrzeuglngelEditField.Value;
                app.VerhltnislhlEditField.Value = app.HinterlngelhEditField.Value / app.FahrzeuglngelEditField.Value;
                VerhaeltnislvlChanged(app);
            end
        end

        % Value changed function: FahrzeuglngelEditField
        function FahrzeuglngelEditFieldValueChanged(app, event)
            if app.VorderlngelvEditField.Value > 0 
                app.HinterlngelhEditField.Value = app.FahrzeuglngelEditField.Value - app.VorderlngelvEditField.Value;
            else
                if app.HinterlngelhEditField.Value > 0 
                    app.VorderlngelvEditField.Value = app.FahrzeuglngelEditField.Value - app.HinterlngelhEditField.Value;
                else
                    app.VorderlngelvEditField.Value = app.FahrzeuglngelEditField.Value / 2;
                    app.HinterlngelhEditField.Value = app.FahrzeuglngelEditField.Value / 2;
                end
            end
            app.VerhltnislvlEditField.Value = app.VorderlngelvEditField.Value / app.FahrzeuglngelEditField.Value;
            app.VerhltnislhlEditField.Value = app.HinterlngelhEditField.Value / app.FahrzeuglngelEditField.Value;
            VerhaeltnislvlChanged(app);
        end

        % Value changed function: HinterlngelhEditField
        function HinterlngelhEditFieldValueChanged(app, event)
            if app.FahrzeuglngelEditField.Value > 0 
                if app.FahrzeuglngelEditField.Value >= app.HinterlngelhEditField.Value
                    app.VorderlngelvEditField.Value = app.FahrzeuglngelEditField.Value - app.HinterlngelhEditField.Value;
                else
                    app.FahrzeuglngelEditField.Value = app.HinterlngelhEditField.Value;
                    app.VorderlngelvEditField.Value = 0;
                end
                app.VerhltnislvlEditField.Value = app.VorderlngelvEditField.Value / app.FahrzeuglngelEditField.Value;
                app.VerhltnislhlEditField.Value = app.HinterlngelhEditField.Value / app.FahrzeuglngelEditField.Value;
                VerhaeltnislvlChanged(app);
            end
        end

        % Value changed function: VerhltnislvlEditField
        function VerhltnislvlEditFieldValueChanged(app, event)
            app.VerhltnislhlEditField.Value = 1 - app.VerhltnislvlEditField.Value;
            app.VorderlngelvEditField.Value = app.VerhltnislvlEditField.Value * app.FahrzeuglngelEditField.Value;
            app.HinterlngelhEditField.Value = app.VerhltnislhlEditField.Value * app.FahrzeuglngelEditField.Value;
            VerhaeltnislvlChanged(app);
        end

        % Value changed function: VerhltnislhlEditField
        function VerhltnislhlEditFieldValueChanged(app, event)
            app.VerhltnislvlEditField.Value = 1 - app.VerhltnislhlEditField.Value;
            app.VorderlngelvEditField.Value = app.VerhltnislvlEditField.Value * app.FahrzeuglngelEditField.Value;
            app.HinterlngelhEditField.Value = app.VerhltnislhlEditField.Value * app.FahrzeuglngelEditField.Value;
            VerhaeltnislvlChanged(app);
        end

        % Value changed function: SGEditField
        function SGEditFieldValueChanged(app, event)
            if app.SGEditField.Value == 0
                app.SGEditField.Value = 0.001;
            else
                if app.SGEditField.Value == -app.EGEditField.Value
                    app.SGEditField.Value = app.SGEditField.Value + 0.001;
                end
            end
            app.ChSternEditField.Value = 1.0 / (app.ErdbeschleunigunggEditField.Value * app.SGEditField.Value);
            app.CvSternEditField.Value = 1.0 / (app.ErdbeschleunigunggEditField.Value * (app.EGEditField.Value + app.SGEditField.Value));   
            app.ChEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislvlEditField.Value * app.ChSternEditField.Value);
            app.CvEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value * app.CvSternEditField.Value); 
        end

        % Value changed function: EGEditField
        function EGEditFieldValueChanged(app, event)
            app.CvSternEditField.Value = 1.0 / (app.ErdbeschleunigunggEditField.Value * (app.EGEditField.Value + app.SGEditField.Value));
            app.CvEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value * app.CvSternEditField.Value);
        end

        % Value changed function: CvSternEditField
        function CvSternEditFieldValueChanged(app, event)
            app.EGEditField.Value = ( 1 / app.CvSternEditField.Value - 1 / app.ChSternEditField.Value) / app.ErdbeschleunigunggEditField.Value;
            app.CvEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value * app.CvSternEditField.Value);
        end

        % Value changed function: ChSternEditField
        function ChSternEditFieldValueChanged(app, event)
            app.SGEditField.Value = 1.0 / (app.ErdbeschleunigunggEditField.Value * app.ChSternEditField.Value);
            app.EGEditField.Value = ( 1 / app.CvSternEditField.Value - 1 / app.ChSternEditField.Value) / app.ErdbeschleunigunggEditField.Value;
            app.ChEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislvlEditField.Value * app.ChSternEditField.Value);
        end

        % Value changed function: ErdbeschleunigunggEditField
        function ErdbeschleunigunggEditFieldValueChanged(app, event)
            app.ChSternEditField.Value = 1.0 / (app.ErdbeschleunigunggEditField.Value * app.SGEditField.Value);
            app.CvSternEditField.Value = 1.0 / (app.ErdbeschleunigunggEditField.Value * (app.EGEditField.Value + app.SGEditField.Value)); 
            app.ChEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislvlEditField.Value * app.ChSternEditField.Value);
            app.CvEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value * app.CvSternEditField.Value);
        end

        % Button pushed function: SimulationstartenButton
        function SimulationstartenButtonPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                runDiagram33(app);
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimulationButtonTangential
        function SimulationButtonTangentialPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                runDiagram37(app);
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Value changed function: PsiEditField
        function PsiEditFieldValueChanged(app, event)
            app.VerhltnislvlEditField.Value = app.PsiEditField.Value;
            VerhltnislvlEditFieldValueChanged(app, event);
        end

        % Value changed function: ChiEditField
        function ChiEditFieldValueChanged(app, event)
            app.hsEditField.Value = app.ChiEditField.Value * app.FahrzeuglngelEditField.Value;
        end

        % Value changed function: hsEditField
        function hsEditFieldValueChanged(app, event)
            app.ChiEditField.Value = app.hsEditField.Value / app.FahrzeuglngelEditField.Value;
        end

        % Selection changed function: ReifenkennlinienartButtonGroup
        function ReifenkennlinienartButtonGroupSelectionChanged(app, event)
            drawReifenkennlinie(app);
        end

        % Value changed function: ReibungmaxEditField
        function ReibungmaxEditFieldValueChanged(app, event)
            drawReifenkennlinie(app);
        end

        % Value changed function: LngsschlupfmaxEditField
        function LngsschlupfmaxEditFieldValueChanged(app, event)
            drawReifenkennlinie(app);
        end

        % Value changed function: CvEditField
        function CvEditFieldValueChanged(app, event)
            app.CvSternEditField.Value = app.CvEditField.Value / (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value);
            CvSternEditFieldValueChanged(app,event);
        end

        % Value changed function: ChEditField
        function ChEditFieldValueChanged(app, event)
            app.ChSternEditField.Value = app.ChEditField.Value / (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislvlEditField.Value);
            ChSternEditFieldValueChanged(app,event);
        end

        % Button pushed function: SimulationButtonKreisV
        function SimulationButtonKreisVPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                app.StatusKonstVelLabel.Text = "Status: Running simulation...";
                Plot_4_6_1(app.UIAxesKonstVel, app.GeschwindigkeitSlider.Value, app.ErdbeschleunigunggEditField.Value, app.VorderlngelvEditField.Value, app.HinterlngelhEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmassemEditField.Value, app.ThetaEditField.Value)
                app.StatusKonstVelLabel.Text = "Status: Simulation finished";
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimulationButtonKreisR
        function SimulationButtonKreisRPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                app.StatusKonstRadLabel.Text = "Status: Running simulation...";
                Plot_4_6_2(app.UIAxesKonstRad, app.RadiusSlider.Value, app.ErdbeschleunigunggEditField.Value, app.VorderlngelvEditField.Value, app.HinterlngelhEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmassemEditField.Value, app.ThetaEditField.Value)
                app.StatusKonstRadLabel.Text = "Status: Simulation finished";
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimulationButtonGier
        function SimulationButtonGierPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                app.StatusKonstGierLabel.Text = "Status: Running simulation...";
                Plot_4_8(app.UIAxesGier, app.ErdbeschleunigunggEditField.Value, app.VorderlngelvEditField.Value, app.HinterlngelhEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmassemEditField.Value, app.ThetaEditField.Value)
                app.StatusKonstGierLabel.Text = "Status: Simulation finished";
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Value changed function: FahrzeugmassemEditField
        function FahrzeugmassemEditFieldValueChanged(app, event)
            app.CvEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislhlEditField.Value * app.CvSternEditField.Value);
            app.ChEditField.Value = (app.ErdbeschleunigunggEditField.Value * app.FahrzeugmassemEditField.Value * app.VerhltnislvlEditField.Value * app.ChSternEditField.Value);
        end

        % Close request function: EinspurmodellUIFigure
        function EinspurmodellUIFigureCloseRequest(app, event)
            close_system('Laengsdynamik',0);
            close_system('ESM',0);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EinspurmodellUIFigure = uifigure('Visible', 'off');
            app.EinspurmodellUIFigure.Name = 'Einspurmodell';
            app.EinspurmodellUIFigure.CloseRequestFcn = createCallbackFcn(app, @EinspurmodellUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.EinspurmodellUIFigure);
            app.BremsvorgangTab = uitab(app.TabGroup);
            app.BremsvorgangTab.Title = 'Bremsvorgang';
            app.UIAxes5 = uiaxes(app.BremsvorgangTab);
            title(app.UIAxes5, 'Streckenverlauf')
            xlabel(app.UIAxes5, 'Zeit t in Sekunden')
            ylabel(app.UIAxes5, 'Strecke in Metern')
            app.UIAxes6 = uiaxes(app.BremsvorgangTab);
            title(app.UIAxes6, 'Geschwindigkeitsverlauf')
            xlabel(app.UIAxes6, 'Zeit t in Sekunden')
            ylabel(app.UIAxes6, 'Geschwindigkeit in km/h')
            app.UIAxes5_2 = uiaxes(app.BremsvorgangTab);
            title(app.UIAxes5_2, 'Beschleunigungsverlauf')
            xlabel(app.UIAxes5_2, 'Zeit t in Sekunden')
            ylabel(app.UIAxes5_2, 'Beschleunigung in m/s^2')
            app.SimulationstartenButton = uibutton(app.BremsvorgangTab, 'push');
            app.SimulationstartenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationstartenButtonPushed, true);
            app.SimulationstartenButton.Text = 'Simulation starten';
            app.StartgeschwindigkeitSliderLabel = uilabel(app.BremsvorgangTab);
            app.StartgeschwindigkeitSliderLabel.Text = 'Startgeschwindigkeit';
            app.StartgeschwindigkeitSlider = uislider(app.BremsvorgangTab);
            app.StartgeschwindigkeitSlider.Limits = [0 150];
            app.StartgeschwindigkeitSlider.Value = 80;
            app.kmhLabel = uilabel(app.BremsvorgangTab);
            app.kmhLabel.Text = 'km/h';
            app.Diagramm33BremsvorgangLabel = uilabel(app.BremsvorgangTab);
            app.Diagramm33BremsvorgangLabel.Text = 'Diagramm 3.3: Bremsvorgang';
            app.StatusBremsLabel = uilabel(app.BremsvorgangTab);
            app.StatusBremsLabel.Text = 'Status: Ready';
            app.EinspurmodellTINF15ITALippeMonningerLabel_2 = uilabel(app.BremsvorgangTab);
            app.EinspurmodellTINF15ITALippeMonningerLabel_2.Text = 'Einspurmodell - TINF15ITA - Lippe,Monninger';
            app.TangentialkraftTab = uitab(app.TabGroup);
            app.TangentialkraftTab.Title = 'Tangentialkraft';
            app.SimulationButtonTangential = uibutton(app.TangentialkraftTab, 'push');
            app.SimulationButtonTangential.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonTangentialPushed, true);
            app.SimulationButtonTangential.Text = 'Simulation starten';
            app.StatusReadyLabel = uilabel(app.TangentialkraftTab);
            app.StatusReadyLabel.Text = 'Status: Ready';
            app.PsiEditFieldLabel = uilabel(app.TangentialkraftTab);
            app.PsiEditFieldLabel.Text = 'Psi 𝜓';
            app.PsiEditField = uieditfield(app.TangentialkraftTab, 'numeric');
            app.PsiEditField.Limits = [0 1];
            app.PsiEditField.ValueChangedFcn = createCallbackFcn(app, @PsiEditFieldValueChanged, true);
            app.PsiEditField.Value = 0.48;
            app.ChiEditFieldLabel = uilabel(app.TangentialkraftTab);
            app.ChiEditFieldLabel.Text = 'Chi 𝜒';
            app.ChiEditField = uieditfield(app.TangentialkraftTab, 'numeric');
            app.ChiEditField.Limits = [0 Inf];
            app.ChiEditField.ValueChangedFcn = createCallbackFcn(app, @ChiEditFieldValueChanged, true);
            app.ChiEditField.Value = 0.178571428571429;
            app.UIAxes2 = uiaxes(app.TangentialkraftTab);
            title(app.UIAxes2, 'Tangentialkraftverteilungsdiagram')
            xlabel(app.UIAxes2, 'FBv/Gg')
            ylabel(app.UIAxes2, 'FBh/Gg')
            app.Diagramm37TangentialkraftverteilungsdiagrammLabel = uilabel(app.TangentialkraftTab);
            app.Diagramm37TangentialkraftverteilungsdiagrammLabel.Text = 'Diagramm 3.7: Tangentialkraftverteilungsdiagramm';
            app.EinspurmodellTINF15ITALippeMonningerLabel_3 = uilabel(app.TangentialkraftTab);
            app.EinspurmodellTINF15ITALippeMonningerLabel_3.Text = 'Einspurmodell - TINF15ITA - Lippe,Monninger';
            app.KreisfahrtTab = uitab(app.TabGroup);
            app.KreisfahrtTab.Title = 'Kreisfahrt';
            app.UIAxesKonstRad = uiaxes(app.KreisfahrtTab);
            title(app.UIAxesKonstRad, 'Konstanter Radius')
            xlabel(app.UIAxesKonstRad, 'ay in [m/s^2]')
            ylabel(app.UIAxesKonstRad, 'δh/is in [rad]')
            app.UIAxesKonstVel = uiaxes(app.KreisfahrtTab);
            title(app.UIAxesKonstVel, 'Konstante Geschwindigkeit')
            xlabel(app.UIAxesKonstVel, 'ay in [m/s^2]')
            ylabel(app.UIAxesKonstVel, 'δh/is in [rad]')
            app.SimulationButtonKreisV = uibutton(app.KreisfahrtTab, 'push');
            app.SimulationButtonKreisV.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonKreisVPushed, true);
            app.SimulationButtonKreisV.Text = 'Simulation starten';
            app.SimulationButtonKreisR = uibutton(app.KreisfahrtTab, 'push');
            app.SimulationButtonKreisR.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonKreisRPushed, true);
            app.SimulationButtonKreisR.Text = 'Simulation starten';
            app.RadiusSliderLabel = uilabel(app.KreisfahrtTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.KreisfahrtTab);
            app.RadiusSlider.Limits = [25 40];
            app.RadiusSlider.Value = 35;
            app.mLabel_5 = uilabel(app.KreisfahrtTab);
            app.mLabel_5.Text = 'm';
            app.GeschwindigkeitSliderLabel = uilabel(app.KreisfahrtTab);
            app.GeschwindigkeitSliderLabel.Text = 'Geschwindigkeit';
            app.GeschwindigkeitSlider = uislider(app.KreisfahrtTab);
            app.GeschwindigkeitSlider.Limits = [10 100];
            app.GeschwindigkeitSlider.Value = 30;
            app.kmhLabel_2 = uilabel(app.KreisfahrtTab);
            app.kmhLabel_2.Text = 'km/h';
            app.Diagramm46KreisfahrtLabel = uilabel(app.KreisfahrtTab);
            app.Diagramm46KreisfahrtLabel.Text = 'Diagramm 4.6: Kreisfahrt';
            app.StatusKonstVelLabel = uilabel(app.KreisfahrtTab);
            app.StatusKonstVelLabel.Text = 'Status: Ready';
            app.StatusKonstRadLabel = uilabel(app.KreisfahrtTab);
            app.StatusKonstRadLabel.Text = 'Status: Ready';
            app.EinspurmodellTINF15ITALippeMonningerLabel_4 = uilabel(app.KreisfahrtTab);
            app.EinspurmodellTINF15ITALippeMonningerLabel_4.Text = 'Einspurmodell - TINF15ITA - Lippe,Monninger';
            app.GierreaktionTab = uitab(app.TabGroup);
            app.GierreaktionTab.Title = 'Gierreaktion';
            app.UIAxesGier = uiaxes(app.GierreaktionTab);
            title(app.UIAxesGier, 'Gierreaktion')
            xlabel(app.UIAxesGier, 'Fahrzeuggeschwindigkeit v in [m/s]')
            ylabel(app.UIAxesGier, 'Giergeschwindigkeit durch Lenkradwinkel')
            app.Diagramm48GierreaktionLabel = uilabel(app.GierreaktionTab);
            app.Diagramm48GierreaktionLabel.Text = 'Diagramm 4.8: Gierreaktion';
            app.SimulationButtonGier = uibutton(app.GierreaktionTab, 'push');
            app.SimulationButtonGier.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonGierPushed, true);
            app.SimulationButtonGier.Text = 'Simulation starten';
            app.StatusKonstGierLabel = uilabel(app.GierreaktionTab);
            app.StatusKonstGierLabel.Text = 'Status: Ready';
            app.EinspurmodellTINF15ITALippeMonningerLabel_5 = uilabel(app.GierreaktionTab);
            app.EinspurmodellTINF15ITALippeMonningerLabel_5.Text = 'Einspurmodell - TINF15ITA - Lippe,Monninger';
            app.FahrzeugparameterTab = uitab(app.TabGroup);
            app.FahrzeugparameterTab.Title = 'Fahrzeugparameter';
            app.FahrzeuglngelEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeuglngelEditFieldLabel.Text = 'Fahrzeuglänge l';
            app.FahrzeuglngelEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.FahrzeuglngelEditField.Limits = [0 Inf];
            app.FahrzeuglngelEditField.ValueChangedFcn = createCallbackFcn(app, @FahrzeuglngelEditFieldValueChanged, true);
            app.FahrzeuglngelEditField.Value = 2.8;
            app.VorderlngelvEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.VorderlngelvEditFieldLabel.Text = 'Vorderlänge lv';
            app.VorderlngelvEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.VorderlngelvEditField.Limits = [0 Inf];
            app.VorderlngelvEditField.ValueChangedFcn = createCallbackFcn(app, @VorderlngelvEditFieldValueChanged, true);
            app.VorderlngelvEditField.Value = 1.344;
            app.HinterlngelhEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.HinterlngelhEditFieldLabel.Text = 'Hinterlänge lh';
            app.HinterlngelhEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.HinterlngelhEditField.Limits = [0 Inf];
            app.HinterlngelhEditField.ValueChangedFcn = createCallbackFcn(app, @HinterlngelhEditFieldValueChanged, true);
            app.HinterlngelhEditField.Value = 1.456;
            app.VerhltnislvlEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.VerhltnislvlEditFieldLabel.Text = 'Verhältnis lv/l';
            app.VerhltnislvlEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.VerhltnislvlEditField.Limits = [0 1];
            app.VerhltnislvlEditField.ValueChangedFcn = createCallbackFcn(app, @VerhltnislvlEditFieldValueChanged, true);
            app.VerhltnislvlEditField.Value = 0.48;
            app.VerhltnislhlEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.VerhltnislhlEditFieldLabel.Text = 'Verhältnis lh/l';
            app.VerhltnislhlEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.VerhltnislhlEditField.Limits = [0 1];
            app.VerhltnislhlEditField.ValueChangedFcn = createCallbackFcn(app, @VerhltnislhlEditFieldValueChanged, true);
            app.VerhltnislhlEditField.Value = 0.52;
            app.CvSternEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.CvSternEditFieldLabel.Text = 'Cv*';
            app.CvSternEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.CvSternEditField.ValueChangedFcn = createCallbackFcn(app, @CvSternEditFieldValueChanged, true);
            app.ChSternEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.ChSternEditFieldLabel.Text = 'Ch*';
            app.ChSternEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ChSternEditField.ValueChangedFcn = createCallbackFcn(app, @ChSternEditFieldValueChanged, true);
            app.SGEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.SGEditFieldLabel.Text = 'SG';
            app.SGEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.SGEditField.ValueChangedFcn = createCallbackFcn(app, @SGEditFieldValueChanged, true);
            app.EGEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.EGEditFieldLabel.Text = 'EG';
            app.EGEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.EGEditField.ValueChangedFcn = createCallbackFcn(app, @EGEditFieldValueChanged, true);
            app.hsEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.hsEditFieldLabel.Text = 'hs';
            app.hsEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.hsEditField.ValueChangedFcn = createCallbackFcn(app, @hsEditFieldValueChanged, true);
            app.hsEditField.Value = 0.5;
            app.ErdbeschleunigunggEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.ErdbeschleunigunggEditFieldLabel.Text = 'Erdbeschleunigung g';
            app.ErdbeschleunigunggEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ErdbeschleunigunggEditField.LowerLimitInclusive = 'off';
            app.ErdbeschleunigunggEditField.Limits = [0 Inf];
            app.ErdbeschleunigunggEditField.ValueChangedFcn = createCallbackFcn(app, @ErdbeschleunigunggEditFieldValueChanged, true);
            app.ErdbeschleunigunggEditField.Value = 9.81;
            app.FahrzeugmassemEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugmassemEditFieldLabel.Text = 'Fahrzeugmasse m';
            app.FahrzeugmassemEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.FahrzeugmassemEditField.ValueChangedFcn = createCallbackFcn(app, @FahrzeugmassemEditFieldValueChanged, true);
            app.FahrzeugmassemEditField.Value = 1550;
            app.ThetaEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ThetaEditField.Value = 2800;
            app.mLabel = uilabel(app.FahrzeugparameterTab);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.FahrzeugparameterTab);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.FahrzeugparameterTab);
            app.mLabel_3.Text = 'm';
            app.kgLabel = uilabel(app.FahrzeugparameterTab);
            app.kgLabel.Text = 'kg';
            app.kgm2Label = uilabel(app.FahrzeugparameterTab);
            app.kgm2Label.Text = 'kg*m^2';
            app.mLabel_4 = uilabel(app.FahrzeugparameterTab);
            app.mLabel_4.Text = 'm';
            app.ms2Label = uilabel(app.FahrzeugparameterTab);
            app.ms2Label.Text = 'm/s^2';
            app.CvEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.CvEditFieldLabel.Text = 'Cv';
            app.CvEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.CvEditField.ValueChangedFcn = createCallbackFcn(app, @CvEditFieldValueChanged, true);
            app.CvEditField.Value = 75000;
            app.ChEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.ChEditFieldLabel.Text = 'Ch';
            app.ChEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ChEditField.ValueChangedFcn = createCallbackFcn(app, @ChEditFieldValueChanged, true);
            app.ChEditField.Value = 150000;
            app.NradLabel = uilabel(app.FahrzeugparameterTab);
            app.NradLabel.Text = 'N/rad';
            app.NradLabel_2 = uilabel(app.FahrzeugparameterTab);
            app.NradLabel_2.Text = 'N/rad';
            app.radLabel = uilabel(app.FahrzeugparameterTab);
            app.radLabel.Text = '1/rad';
            app.radLabel_3 = uilabel(app.FahrzeugparameterTab);
            app.radLabel_3.Text = '1/rad';
            app.rads2mLabel = uilabel(app.FahrzeugparameterTab);
            app.rads2mLabel.Text = 'rad*s^2/m';
            app.rads2mLabel_2 = uilabel(app.FahrzeugparameterTab);
            app.rads2mLabel_2.Text = 'rad*s^2/m';
            app.FahrzeugparameterLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugparameterLabel.Text = 'Fahrzeugparameter';
            app.FahrzeugparameterLabel_2 = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugparameterLabel_2.Text = 'Parametereinstellungen für die Simulation des Fahrzeugsverhaltens in allen Diagrammen';
            app.FahrzeuggreLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeuggreLabel.Text = 'Fahrzeuggröße';
            app.GewichtundKrfteLabel = uilabel(app.FahrzeugparameterTab);
            app.GewichtundKrfteLabel.Text = 'Gewicht und Kräfte';
            app.FahrzeugverhaltenLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugverhaltenLabel.Text = 'Fahrzeugverhalten';
            app.Label = uilabel(app.FahrzeugparameterTab);
            app.Label.Text = 'Hinweis: Parameter, die abhängig voneinander sind, werden automatisch angepasst. Die Priorität gilt dem neu eingetragenen Wert.';
            app.EinspurmodellTINF15ITALippeMonningerLabel_6 = uilabel(app.FahrzeugparameterTab);
            app.EinspurmodellTINF15ITALippeMonningerLabel_6.Text = 'Einspurmodell - TINF15ITA - Lippe,Monninger';
            app.ReifenkennlinienTab = uitab(app.TabGroup);
            app.ReifenkennlinienTab.Title = 'Reifenkennlinien';
            app.ReifenkennlinienartButtonGroup = uibuttongroup(app.ReifenkennlinienTab);
            app.ReifenkennlinienartButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReifenkennlinienartButtonGroupSelectionChanged, true);
            app.ReifenkennlinienartButtonGroup.Title = 'Reifenkennlinienart';
            app.LinearButton = uiradiobutton(app.ReifenkennlinienartButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.AsphaltButton = uiradiobutton(app.ReifenkennlinienartButtonGroup);
            app.AsphaltButton.Text = 'Asphalt';
            app.KiesloseButton = uiradiobutton(app.ReifenkennlinienartButtonGroup);
            app.KiesloseButton.Text = 'Kies lose';
            app.EisButton = uiradiobutton(app.ReifenkennlinienartButtonGroup);
            app.EisButton.Text = 'Eis';
            app.ReibungmaxEditFieldLabel = uilabel(app.ReifenkennlinienTab);
            app.ReibungmaxEditFieldLabel.Text = 'Reibung 𝜇 max';
            app.ReibungmaxEditField = uieditfield(app.ReifenkennlinienTab, 'numeric');
            app.ReibungmaxEditField.LowerLimitInclusive = 'off';
            app.ReibungmaxEditField.Limits = [0 Inf];
            app.ReibungmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ReibungmaxEditFieldValueChanged, true);
            app.ReibungmaxEditField.Value = 1;
            app.LngsschlupfmaxEditFieldLabel = uilabel(app.ReifenkennlinienTab);
            app.LngsschlupfmaxEditFieldLabel.Text = 'Längsschlupf 𝝀 max';
            app.LngsschlupfmaxEditField = uieditfield(app.ReifenkennlinienTab, 'numeric');
            app.LngsschlupfmaxEditField.Limits = [0.2 0.6];
            app.LngsschlupfmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LngsschlupfmaxEditFieldValueChanged, true);
            app.LngsschlupfmaxEditField.Value = 0.25;
            app.UIAxes4 = uiaxes(app.ReifenkennlinienTab);
            title(app.UIAxes4, 'Reifenkennlinie')
            xlabel(app.UIAxes4, 'Längsschlupf 𝝀 ')
            ylabel(app.UIAxes4, 'Reibungszahl 𝜇')
            app.ReifenkennlinieLabel = uilabel(app.ReifenkennlinienTab);
            app.ReifenkennlinieLabel.Text = 'Reifenkennlinie';
            app.FahrzeugparameterLabel_3 = uilabel(app.ReifenkennlinienTab);
            app.FahrzeugparameterLabel_3.Text = 'Parameterisierung der Reifenkennlinie für den Bremsvorgang';
            app.EinspurmodellTINF15ITALippeMonningerLabel_7 = uilabel(app.ReifenkennlinienTab);
            app.EinspurmodellTINF15ITALippeMonningerLabel_7.Text = 'Einspurmodell - TINF15ITA - Lippe,Monninger';
            % Show the figure after all components are created
            app.EinspurmodellUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EinspurmodellUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EinspurmodellUIFigure)
        end
    end
end