classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EinspurmodellUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BremsvorgangTab                 matlab.ui.container.Tab
        BremsvorgangLabel               matlab.ui.control.Label
        StartgeschwindigkeitkmhLabel    matlab.ui.control.Label
        GeschwindigkeitBremsSlider      matlab.ui.control.Slider
        BeschleunigungAxes              matlab.ui.control.UIAxes
        StreckeAxes                     matlab.ui.control.UIAxes
        GeschwindigkeitAxes             matlab.ui.control.UIAxes
        StatusBremsvorgangLabel         matlab.ui.control.Label
        SimStartBremsvorgangButton      matlab.ui.control.Button
        TangentialkraftTab              matlab.ui.container.Tab
        TangentialkraftLabel            matlab.ui.control.Label
        TangentialkraftAxes             matlab.ui.control.UIAxes
        PsiEditFieldLabel               matlab.ui.control.Label
        PsiEditField                    matlab.ui.control.NumericEditField
        ChiEditFieldLabel               matlab.ui.control.Label
        ChiEditField                    matlab.ui.control.NumericEditField
        StatusTangentialkraftLabel      matlab.ui.control.Label
        SimStartTangentialkraftButton   matlab.ui.control.Button
        KreisfahrtTab                   matlab.ui.container.Tab
        KreisfahrtLabel                 matlab.ui.control.Label
        KreisKonstVAxes                 matlab.ui.control.UIAxes
        KreisKonstRAxes                 matlab.ui.control.UIAxes
        GeschwindigkeitkmhLabel         matlab.ui.control.Label
        GeschwindigkeitKreisfahrtSlider  matlab.ui.control.Slider
        StatusKreisfahrtLabel           matlab.ui.control.Label
        RadiusmSliderLabel              matlab.ui.control.Label
        RadiusKreisfahrtSlider          matlab.ui.control.Slider
        SimStartKreisfahrtVButton       matlab.ui.control.Button
        SimStartKreisfahrtRButton       matlab.ui.control.Button
        GierreaktionTab                 matlab.ui.container.Tab
        GierreaktionLabel               matlab.ui.control.Label
        StatusGierreaktionLabel         matlab.ui.control.Label
        GierreaktionAxes                matlab.ui.control.UIAxes
        SimStartGierreaktionButton      matlab.ui.control.Button
        ReifekennlinieTab               matlab.ui.container.Tab
        ReifenkennlinieLabel            matlab.ui.control.Label
        ReifenkennlinieAxes             matlab.ui.control.UIAxes
        ReifenkennlinieDetailsLabel     matlab.ui.control.Label
        KennlinienartButtonGroup        matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        AsphaltButton                   matlab.ui.control.RadioButton
        KiesloseButton                  matlab.ui.control.RadioButton
        EisButton                       matlab.ui.control.RadioButton
        ReibungmaxEditFieldLabel        matlab.ui.control.Label
        ReibungmaxEditField             matlab.ui.control.NumericEditField
        LngsschlupfmaxEditFieldLabel    matlab.ui.control.Label
        LangsschlupfmaxEditField        matlab.ui.control.NumericEditField
        FahrzeugparameterTab            matlab.ui.container.Tab
        FahrzeugparameterLabel          matlab.ui.control.Label
        FahrzeugparamDetailSLAbel       matlab.ui.control.Label
        FahrzeuglngelmEditFieldLabel    matlab.ui.control.Label
        FahrzeuglangeEditField          matlab.ui.control.NumericEditField
        VorderlngelvmLabel              matlab.ui.control.Label
        VorderlangeEditField            matlab.ui.control.NumericEditField
        HinterlngelhmEditFieldLabel     matlab.ui.control.Label
        HinterlangeEditField            matlab.ui.control.NumericEditField
        VerhltnislvlLabel               matlab.ui.control.Label
        VerhaltnisVEditField            matlab.ui.control.NumericEditField
        VerhltnislhlLabel               matlab.ui.control.Label
        VerhaltnisHEditField            matlab.ui.control.NumericEditField
        FahrzeugmassemkgEditFieldLabel  matlab.ui.control.Label
        FahrzeugmasseEditField          matlab.ui.control.NumericEditField
        ThetakgmEditFieldLabel          matlab.ui.control.Label
        ThetaEditField                  matlab.ui.control.NumericEditField
        ErdbeschleunigunggmsEditFieldLabel  matlab.ui.control.Label
        ErdbeschleunigungEditField      matlab.ui.control.NumericEditField
        Cv1radLabel                     matlab.ui.control.Label
        CvSternEditField                matlab.ui.control.NumericEditField
        Ch1radLabel                     matlab.ui.control.Label
        ChSternEditField                matlab.ui.control.NumericEditField
        CvNradEditFieldLabel            matlab.ui.control.Label
        CvEditField                     matlab.ui.control.NumericEditField
        ChNradLabel                     matlab.ui.control.Label
        ChEditField                     matlab.ui.control.NumericEditField
        SGradsmEditFieldLabel           matlab.ui.control.Label
        SGEditField                     matlab.ui.control.NumericEditField
        EGradsmEditFieldLabel           matlab.ui.control.Label
        EGEditField                     matlab.ui.control.NumericEditField
        hsmEditFieldLabel               matlab.ui.control.Label
        hsEditField                     matlab.ui.control.NumericEditField
        FahrzeugmaseLabel               matlab.ui.control.Label
        VerhaltenLabel                  matlab.ui.control.Label
        AbhngigeGrenwerdenautomatischberechnetLabel  matlab.ui.control.Label
        errorLabel                      matlab.ui.control.Label
        EinspurmodellLabel              matlab.ui.control.Label
        STGTINF16ITALabel               matlab.ui.control.Label
        NamesLabel                      matlab.ui.control.Label
    end


    properties (Access = private)
        
        simRunning = 0
        
    end

    methods (Access = private)
        
        function runDiagram_3_3(app)
            writeStatusLabels(app, "Loading Simulink model...");
            load_system('LD_V2');
            params = get_param('LD_V2','modelworkspace');
            writeStatusLabels(app, "Running simulation...");
            
            l_v = app.VorderlangeEditField.Value * 1.0;
            h_s = app.hsEditField.Value * 1.0;
            l = app.FahrzeuglangeEditField.Value * 1.0;
            g = app.ErdbeschleunigungEditField.Value * 1.0;
            v0 = app.GeschwindigkeitBremsSlider.Value / 3.6;
            
            [reifenX, reifenY] = getReifenparameter(app,app.KennlinienartButtonGroup.SelectedObject.Text);
            max_my = max(reifenY);
            params.assignin('reifenY', reifenY);
            params.assignin('reifenX', reifenX);
            params.assignin('l_v',l_v);
            params.assignin('h_s',h_s);
            params.assignin('l',l);
            params.assignin('g',g);
            params.assignin('v0',v0);
            params.assignin('z_vals',[0.0 0.0 0.1 1.0 1.0] * max_my);
            params.assignin('z_time',[0.0 1.5 1.95 2.15 10.0]);
            params.assignin('zv_z',1.0);
            params.assignin('zh_z',1.0);
            params.assignin('useController',1);
            run_time = int32(max([10.0 (4 + v0 / (max_my * g))]));
            run_time = run_time - mod(run_time, 2);
            
            output = sim('LD_V2.slx','Solver','ode4','StopTime',num2str(run_time),'FixedStep','0.01');
            
            a = output.a;
            v = output.v;
            s = output.s;
            hold(app.BeschleunigungAxes,'off');
            plot(app.BeschleunigungAxes, a.Time, a.Data, 'LineWidth', 2, 'Color', [0 0 0.3]);
            hold(app.GeschwindigkeitAxes,'off');
            plot(app.GeschwindigkeitAxes, v.Time, v.Data * 3.6, 'LineWidth', 2, 'Color', [1.0 0.5 0.3]);
            hold(app.StreckeAxes,'off');
            plot(app.StreckeAxes, s.Time, s.Data, 'LineWidth', 2, 'Color', [0.8 0 0]);
            
            writeStatusLabels(app, "Simulation finished");
            close_system('LD_V2',0);
        end
        
        function runDiagram_3_7(app)
            writeStatusLabels(app, "Loading Simulink model...");
            load_system('LD_V2');
            params = get_param('LD_V2','modelworkspace');
            writeStatusLabels(app, "Running simulation...");
            
            l_v = app.VorderlangeEditField.Value * 1.0;
            h_s = app.hsEditField.Value * 1.0;
            l = app.FahrzeuglangeEditField.Value * 1.0;
            g = app.ErdbeschleunigungEditField.Value * 1.0;
            v0 = app.GeschwindigkeitBremsSlider.Value / 3.6;
            
            params.assignin('l_v',l_v);
            params.assignin('h_s',h_s);
            params.assignin('l',l);
            params.assignin('g',g);
            params.assignin('v0',v0);
            [reifenX, reifenY] = getReifenparameter(app,app.KennlinienartButtonGroup.SelectedObject.Text);
            params.assignin('reifenY', reifenY);
            params.assignin('reifenX', reifenX);
            
            psi = l_v / l;
            chi = h_s / l;
            zmin = (-(1-psi)/chi);
            zmax = (psi/chi);
            z_vals = (zmin:0.01:zmax);
            num = size(z_vals);
            z_time = (1:num(2))*0.01;
            FBhMaxVal = psi*psi / (4 * chi);
            FBhMaxFBv = psi * (2 - psi) / (4 * chi);
            FBvMaxVal = - (1 - psi) * (1 - psi) / (4 * chi);
            FBvMaxFBh = - (1 - psi * psi) / (4 * chi);
                    
            params.assignin('z_vals',z_vals);
            params.assignin('z_time',z_time);
            params.assignin('zv_z',1.0);
            params.assignin('zh_z',1.0);
            params.assignin('useController',0);
            
            output = sim('LD_V2.slx','Solver','ode4','StopTime','10.0','FixedStep','0.01');
            
            FBv = output.FBv_Gg.Data; 
            FBh = output.FBh_Gg.Data;
            
            hold(app.TangentialkraftAxes, 'off');
            plot(app.TangentialkraftAxes, FBv,FBh, 'LineWidth', 2);
            hold(app.TangentialkraftAxes, 'on');
            plot(app.TangentialkraftAxes, [0 0],[(zmin * 1.1) (FBhMaxVal * 1.3)], 'LineWidth', 1.5, 'Color', [0.2 0.2 0.2]);
            plot(app.TangentialkraftAxes, [(FBvMaxVal * 1.3) (zmax * 1.1)], [0 0], 'LineWidth', 1.5, 'Color', [0.2 0.2 0.2]);
            plot(app.TangentialkraftAxes, [0 FBhMaxFBv FBhMaxFBv],[FBhMaxVal FBhMaxVal 0], 'LineWidth', 1.0, 'Color', [0.5 0.5 0.5]);
            plot(app.TangentialkraftAxes, [0 FBvMaxVal FBvMaxVal],[FBvMaxFBh FBvMaxFBh 0], 'LineWidth', 1.0, 'Color', [0.5 0.5 0.5]);
            plot(app.TangentialkraftAxes, FBvMaxVal, FBvMaxFBh, '*', 'LineWidth', 3.0, 'Color', [0.0 0.0 0.0]);
            plot(app.TangentialkraftAxes, FBhMaxFBv, FBhMaxVal, '*', 'LineWidth', 3.0, 'Color', [0.0 0.0 0.0]);
            grid(app.TangentialkraftAxes, 'on');
            %legend(app.TangentialkraftAxes, 'Tangentialkraftverteilungsdiagramms');
            writeStatusLabels(app, "Simulation finished");
            close_system('LD_V2',0);
        end
        
        function [xVals, yVals] = getReifenparameter(app, name)
            xVals = [];
            yVals = [];
            if name == "Linear"
                xVals = [0.0 app.LangsschlupfmaxEditField.Value 1.0];
                yVals = [0.0 app.ReibungmaxEditField.Value app.ReibungmaxEditField.Value];
            else 
                if name == "Asphalt"
                    xVals = [0 0.05 0.1 0.15 0.2 0.25 (0.2 / app.LangsschlupfmaxEditField.Value)] * (app.LangsschlupfmaxEditField.Value / 0.2);
                    yVals = [0 0.3636 0.8182 0.9545 1.0000 0.9909 0.8182] * app.ReibungmaxEditField.Value;
                else 
                    if name == "Kies lose"
                        xVals = [0 0.05 0.1 0.15 0.2 0.25 ((0.2 / app.LangsschlupfmaxEditField.Value) * 0.9) (0.2 / app.LangsschlupfmaxEditField.Value)] * (app.LangsschlupfmaxEditField.Value / 0.2);
                        yVals = [0 0.2 0.32 0.4 0.45 0.47 0.52 0.55] * app.ReibungmaxEditField.Value;
                    else
                        if name == "Eis"
                            xVals = [0 0.05 0.1 0.15 0.18 0.2 (0.2 / app.LangsschlupfmaxEditField.Value)] * (app.LangsschlupfmaxEditField.Value / 0.2);
                            yVals = [0 0.1 0.15 0.185 0.195 0.2 0.2] * app.ReibungmaxEditField.Value;
                        end
                    end
                end
            end
        end
        
        function VerhaeltnisLChanged(app)
            app.PsiEditField.Value = app.VerhaltnisVEditField.Value;
            app.ChEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisVEditField.Value * app.ChSternEditField.Value);
            app.CvEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value * app.CvSternEditField.Value);
        end
        
        function drawReifenkennlinie(app)
            selectedButton = app.KennlinienartButtonGroup.SelectedObject.Text;
            [xVals, yVals] = getReifenparameter(app, selectedButton);
            hold(app.ReifenkennlinieAxes, 'off');
            plot(app.ReifenkennlinieAxes, xVals, yVals, 'LineWidth', 2, 'Color', [0 0 0.3]);
            hold(app.ReifenkennlinieAxes, 'on');
            grid(app.ReifenkennlinieAxes, 'on');
            ylim(app.ReifenkennlinieAxes, [0 app.ReibungmaxEditField.Value]);
        end
        
        function disableButtons(app)
            app.SimStartBremsvorgangButton.Enable = "Off";
            app.SimStartTangentialkraftButton.Enable = "Off";
            app.SimStartKreisfahrtVButton.Enable = "Off";
            app.SimStartKreisfahrtRButton.Enable = "Off";
            app.SimStartGierreaktionButton.Enable = "Off";
        end
        
        function enableButtons(app)
            app.SimStartBremsvorgangButton.Enable = "On";
            app.SimStartTangentialkraftButton.Enable = "On";
            app.SimStartKreisfahrtVButton.Enable = "On";
            app.SimStartKreisfahrtRButton.Enable = "On";
            app.SimStartGierreaktionButton.Enable = "On";
        end
        
        function writeStatusLabels(app, text)
            app.StatusBremsvorgangLabel.Text = text;
            app.StatusTangentialkraftLabel.Text = text;
            app.StatusKreisfahrtLabel.Text = text;
            app.StatusGierreaktionLabel.Text = text;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.errorLabel.Text = "";
            writeStatusLabels(app, "Simulation ready");
            drawReifenkennlinie(app);
            CvEditFieldValueChanged(app,0);
            ChEditFieldValueChanged(app,0);
        end

        % Value changed function: PsiEditField
        function PsiEditFieldValueChanged(app, event)
            app.VerhaltnisVEditField.Value = app.PsiEditField.Value;
            VerhaltnisVEditFieldValueChanged(app, event);
        end

        % Value changed function: ChiEditField
        function ChiEditFieldValueChanged(app, event)
            app.hsEditField.Value = app.ChiEditField.Value * app.FahrzeuglangeEditField.Value;
        end

        % Button pushed function: SimStartBremsvorgangButton
        function SimStartBremsvorgangButtonPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                runDiagram_3_3(app);
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimStartTangentialkraftButton
        function SimStartTangentialkraftButtonPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                runDiagram_3_7(app);
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimStartKreisfahrtVButton
        function SimStartKreisfahrtVButtonPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                writeStatusLabels(app, "Status: Running simulation...");
                Plot_4_6_constant_velocity(app.KreisKonstVAxes, app.GeschwindigkeitKreisfahrtSlider.Value, app.ErdbeschleunigungEditField.Value, app.VorderlangeEditField.Value, app.HinterlangeEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmasseEditField.Value, app.ThetaEditField.Value)
                writeStatusLabels(app, "Status: Simulation finished");
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimStartKreisfahrtRButton
        function SimStartKreisfahrtRButtonPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                writeStatusLabels(app, "Status: Running simulation...");
                Plot_4_6_constant_radius(app.KreisKonstRAxes, app.RadiusKreisfahrtSlider.Value, app.ErdbeschleunigungEditField.Value, app.VorderlangeEditField.Value, app.HinterlangeEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmasseEditField.Value, app.ThetaEditField.Value)
                writeStatusLabels(app, "Status: Simulation finished");
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Button pushed function: SimStartGierreaktionButton
        function SimStartGierreaktionButtonPushed(app, event)
            if app.simRunning == 0
                disableButtons(app);
                app.simRunning = 1;
                writeStatusLabels(app, "Status: Running simulation...");
                Plot_4_8(app.GierreaktionAxes, app.ErdbeschleunigungEditField.Value, app.VorderlangeEditField.Value, app.HinterlangeEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmasseEditField.Value, app.ThetaEditField.Value)
                writeStatusLabels(app, "Status: Simulation finished");
                app.simRunning = 0;
                enableButtons(app);
            end
        end

        % Value changed function: ReibungmaxEditField
        function ReibungmaxEditFieldValueChanged(app, event)
            drawReifenkennlinie(app);
        end

        % Value changed function: LangsschlupfmaxEditField
        function LangsschlupfmaxEditFieldValueChanged(app, event)
            drawReifenkennlinie(app);
        end

        % Selection changed function: KennlinienartButtonGroup
        function KennlinienartButtonGroupSelectionChanged(app, event)
            drawReifenkennlinie(app);
        end

        % Value changed function: FahrzeuglangeEditField
        function FahrzeuglangeEditFieldValueChanged(app, event)
            app.errorLabel.Text = "";
            
            if app.VorderlangeEditField.Value + app.HinterlangeEditField.Value ~= app.FahrzeuglangeEditField.Value
                app.errorLabel.Text = "Voderlänge und Hinterlänge müssen zusammen die Gesamtlänge des Fahrzeugs ergeben!";
            end
             
            if app.FahrzeuglangeEditField.Value > 0
                app.VerhaltnisVEditField.Value = app.VorderlangeEditField.Value / app.FahrzeuglangeEditField.Value;
                app.VerhaltnisHEditField.Value = app.HinterlangeEditField.Value / app.FahrzeuglangeEditField.Value;
            end
             
            VerhaeltnisLChanged(app);
        end

        % Value changed function: VorderlangeEditField
        function VorderlangeEditFieldValueChanged(app, event)
            app.errorLabel.Text = "";
            
            if app.VorderlangeEditField.Value + app.HinterlangeEditField.Value ~= app.FahrzeuglangeEditField.Value
                app.errorLabel.Text = "Voderlänge und Hinterlänge müssen zusammen die Gesamtlänge des Fahrzeugs ergeben!";
            end
             
            if app.FahrzeuglangeEditField.Value > 0
                app.VerhaltnisVEditField.Value = app.VorderlangeEditField.Value / app.FahrzeuglangeEditField.Value;
            end
            
            VerhaeltnisLChanged(app);
        end

        % Value changed function: HinterlangeEditField
        function HinterlangeEditFieldValueChanged(app, event)
            app.errorLabel.Text = "";
            
            if app.VorderlangeEditField.Value + app.HinterlangeEditField.Value ~= app.FahrzeuglangeEditField.Value
                app.errorLabel.Text = "Voderlänge und Hinterlänge müssen zusammen die Gesamtlänge des Fahrzeugs ergeben!";
            end
             
            if app.FahrzeuglangeEditField.Value > 0
                app.VerhaltnisHEditField.Value = app.HinterlangeEditField.Value / app.FahrzeuglangeEditField.Value;
            end
            
            VerhaeltnisLChanged(app);
        end

        % Value changed function: VerhaltnisVEditField
        function VerhaltnisVEditFieldValueChanged(app, event)
            if app.FahrzeuglangeEditField.Value > 0
                app.VorderlangeEditField.Value = app.VerhaltnisVEditField.Value * app.FahrzeuglangeEditField.Value;
            end
            
            VerhaeltnisLChanged(app);
        end

        % Value changed function: VerhaltnisHEditField
        function VerhaltnisHEditFieldValueChanged(app, event)
            if app.FahrzeuglangeEditField.Value > 0
                app.VoderlngelhmEditField.Value = app.VerhaltnisHEditField.Value * app.FahrzeuglangeEditField.Value;
            end
            
            VerhaeltnisLChanged(app);
        end

        % Value changed function: FahrzeugmasseEditField
        function FahrzeugmasseEditFieldValueChanged(app, event)
            app.CvEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value * app.CvSternEditField.Value);
            app.ChEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisVEditField.Value * app.ChSternEditField.Value);
        end

        % Value changed function: CvSternEditField
        function CvSternEditFieldValueChanged(app, event)
            app.EGEditField.Value = ( 1 / app.CvSternEditField.Value - 1 / app.ChSternEditField.Value) / app.ErdbeschleunigungEditField.Value;
            app.CvEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value * app.CvSternEditField.Value);
        end

        % Value changed function: ChSternEditField
        function ChSternEditFieldValueChanged(app, event)
            app.SGEditField.Value = 1.0 / (app.ErdbeschleunigungEditField.Value * app.ChSternEditField.Value);
            app.EGEditField.Value = (1 / app.CvSternEditField.Value - 1 / app.ChSternEditField.Value) / app.ErdbeschleunigungEditField.Value;
            app.ChEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisVEditField.Value * app.ChSternEditField.Value);
        end

        % Value changed function: CvEditField
        function CvEditFieldValueChanged(app, event)
            app.CvSternEditField.Value = app.CvEditField.Value / (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value);
            CvSternEditFieldValueChanged(app,event);
        end

        % Value changed function: ChEditField
        function ChEditFieldValueChanged(app, event)
            app.ChSternEditField.Value = app.ChEditField.Value / (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisVEditField.Value);
            ChSternEditFieldValueChanged(app,event);
        end

        % Value changed function: SGEditField
        function SGEditFieldValueChanged(app, event)
            if app.SGEditField.Value == 0
                app.SGEditField.Value = 0.001;
            else
                if app.SGEditField.Value == -app.EGEditField.Value
                    app.SGEditField.Value = app.SGEditField.Value + 0.001;
                end
            end
            
            app.ChSternEditField.Value = 1.0 / (app.ErdbeschleunigungEditField.Value * app.SGEditField.Value);
            app.CvSternEditField.Value = 1.0 / (app.ErdbeschleunigungEditField.Value * (app.EGEditField.Value + app.SGEditField.Value));   
            app.ChEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisVEditField.Value * app.ChSternEditField.Value);
            app.CvEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value * app.CvSternEditField.Value); 
        end

        % Value changed function: EGEditField
        function EGEditFieldValueChanged(app, event)
            app.CvSternEditField.Value = 1.0 / (app.ErdbeschleunigungEditField.Value * (app.EGEditField.Value + app.SGEditField.Value));
            app.CvEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value * app.CvSternEditField.Value);
        end

        % Value changed function: hsEditField
        function hsEditFieldValueChanged(app, event)
            app.ChiEditField.Value = app.hsEditField.Value / app.FahrzeuglangelEditField.Value;
        end

        % Value changed function: ErdbeschleunigungEditField
        function ErdbeschleunigungEditFieldValueChanged(app, event)
            app.ChSternEditField.Value = 1.0 / (app.ErdbeschleunigungEditField.Value * app.SGEditField.Value);
            app.CvSternEditField.Value = 1.0 / (app.ErdbeschleunigungEditField.Value * (app.EGEditField.Value + app.SGEditField.Value)); 
            app.ChEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisVEditField.Value * app.ChSternEditField.Value);
            app.CvEditField.Value = (app.ErdbeschleunigungEditField.Value * app.FahrzeugmasseEditField.Value * app.VerhaltnisHEditField.Value * app.CvSternEditField.Value);
        end

        % Close request function: EinspurmodellUIFigure
        function EinspurmodellUIFigureCloseRequest(app, event)
            close_system('LD_V2',0);
            close_system('QD_V1',0);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EinspurmodellUIFigure = uifigure('Visible', 'off');
            app.EinspurmodellUIFigure.Name = 'Einspurmodell';
            app.EinspurmodellUIFigure.CloseRequestFcn = createCallbackFcn(app, @EinspurmodellUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.EinspurmodellUIFigure);
            app.BremsvorgangTab = uitab(app.TabGroup);
            app.BremsvorgangTab.Title = 'Bremsvorgang';
            app.BremsvorgangLabel = uilabel(app.BremsvorgangTab);
            app.BremsvorgangLabel.Text = 'Bremsvorgang (Diagramm 3.3)';
            app.StartgeschwindigkeitkmhLabel = uilabel(app.BremsvorgangTab);
            app.StartgeschwindigkeitkmhLabel.Text = 'Startgeschwindigkeit [km/h]';
            app.GeschwindigkeitBremsSlider = uislider(app.BremsvorgangTab);
            app.GeschwindigkeitBremsSlider.Limits = [0 150];
            app.GeschwindigkeitBremsSlider.Value = 80;
            app.BeschleunigungAxes = uiaxes(app.BremsvorgangTab);
            title(app.BeschleunigungAxes, 'Beschleunigung')
            xlabel(app.BeschleunigungAxes, 'Zeit t [s]')
            ylabel(app.BeschleunigungAxes, 'Beschleunigung [m/s²]')
            app.StreckeAxes = uiaxes(app.BremsvorgangTab);
            title(app.StreckeAxes, 'Strecke')
            xlabel(app.StreckeAxes, 'Zeit t [s]')
            ylabel(app.StreckeAxes, 'Strecke [m]')
            app.GeschwindigkeitAxes = uiaxes(app.BremsvorgangTab);
            title(app.GeschwindigkeitAxes, 'Geschwindigkeit')
            xlabel(app.GeschwindigkeitAxes, 'Zeit t [s]')
            ylabel(app.GeschwindigkeitAxes, 'Geschwindigkeit [km/h]')
            app.StatusBremsvorgangLabel = uilabel(app.BremsvorgangTab);
            app.StatusBremsvorgangLabel.Text = 'Ready';
            app.SimStartBremsvorgangButton = uibutton(app.BremsvorgangTab, 'push');
            app.SimStartBremsvorgangButton.ButtonPushedFcn = createCallbackFcn(app, @SimStartBremsvorgangButtonPushed, true);
            app.SimStartBremsvorgangButton.Text = 'Simulation starten';
            app.TangentialkraftTab = uitab(app.TabGroup);
            app.TangentialkraftTab.Title = 'Tangentialkraft';
            app.TangentialkraftLabel = uilabel(app.TangentialkraftTab);
            app.TangentialkraftLabel.Text = 'Tangentialkraft (Diagramm 3.7)';
            app.TangentialkraftAxes = uiaxes(app.TangentialkraftTab);
            title(app.TangentialkraftAxes, 'Tangentialkraftsverteilung')
            xlabel(app.TangentialkraftAxes, 'FBv/Gg')
            ylabel(app.TangentialkraftAxes, 'FBh/Gg')
            app.PsiEditFieldLabel = uilabel(app.TangentialkraftTab);
            app.PsiEditFieldLabel.Text = 'Psi ψ:';
            app.PsiEditField = uieditfield(app.TangentialkraftTab, 'numeric');
            app.PsiEditField.Limits = [0 1];
            app.PsiEditField.ValueChangedFcn = createCallbackFcn(app, @PsiEditFieldValueChanged, true);
            app.PsiEditField.Value = 0.48;
            app.ChiEditFieldLabel = uilabel(app.TangentialkraftTab);
            app.ChiEditFieldLabel.Text = 'Chi χ:';
            app.ChiEditField = uieditfield(app.TangentialkraftTab, 'numeric');
            app.ChiEditField.Limits = [0 Inf];
            app.ChiEditField.ValueChangedFcn = createCallbackFcn(app, @ChiEditFieldValueChanged, true);
            app.ChiEditField.Value = 0.178571428571429;
            app.StatusTangentialkraftLabel = uilabel(app.TangentialkraftTab);
            app.StatusTangentialkraftLabel.Text = 'Ready';
            app.SimStartTangentialkraftButton = uibutton(app.TangentialkraftTab, 'push');
            app.SimStartTangentialkraftButton.ButtonPushedFcn = createCallbackFcn(app, @SimStartTangentialkraftButtonPushed, true);
            app.SimStartTangentialkraftButton.Text = 'Simulation starten';
            app.KreisfahrtTab = uitab(app.TabGroup);
            app.KreisfahrtTab.Title = 'Kreisfahrt';
            app.KreisfahrtLabel = uilabel(app.KreisfahrtTab);
            app.KreisfahrtLabel.Text = 'Kreisfahrt (Diagramm 4.6)';
            app.KreisKonstVAxes = uiaxes(app.KreisfahrtTab);
            title(app.KreisKonstVAxes, 'Konstante Geschwindigkeit')
            xlabel(app.KreisKonstVAxes, 'ay [m/s²]')
            ylabel(app.KreisKonstVAxes, 'δh/ls [rad]')
            app.KreisKonstRAxes = uiaxes(app.KreisfahrtTab);
            title(app.KreisKonstRAxes, 'Konstanter Radius')
            xlabel(app.KreisKonstRAxes, 'ay [m/s²]')
            ylabel(app.KreisKonstRAxes, 'δh/ls [rad]')
            app.GeschwindigkeitkmhLabel = uilabel(app.KreisfahrtTab);
            app.GeschwindigkeitkmhLabel.Text = 'Geschwindigkeit [km/h]';
            app.GeschwindigkeitKreisfahrtSlider = uislider(app.KreisfahrtTab);
            app.GeschwindigkeitKreisfahrtSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.GeschwindigkeitKreisfahrtSlider.Value = 50;
            app.StatusKreisfahrtLabel = uilabel(app.KreisfahrtTab);
            app.StatusKreisfahrtLabel.Text = 'Ready';
            app.RadiusmSliderLabel = uilabel(app.KreisfahrtTab);
            app.RadiusmSliderLabel.Text = 'Radius [m]';
            app.RadiusKreisfahrtSlider = uislider(app.KreisfahrtTab);
            app.RadiusKreisfahrtSlider.Limits = [0 50];
            app.RadiusKreisfahrtSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.RadiusKreisfahrtSlider.Value = 30;
            app.SimStartKreisfahrtVButton = uibutton(app.KreisfahrtTab, 'push');
            app.SimStartKreisfahrtVButton.ButtonPushedFcn = createCallbackFcn(app, @SimStartKreisfahrtVButtonPushed, true);
            app.SimStartKreisfahrtVButton.Text = 'Simulation starten';
            app.SimStartKreisfahrtRButton = uibutton(app.KreisfahrtTab, 'push');
            app.SimStartKreisfahrtRButton.ButtonPushedFcn = createCallbackFcn(app, @SimStartKreisfahrtRButtonPushed, true);
            app.SimStartKreisfahrtRButton.Text = 'Simulation starten';
            app.GierreaktionTab = uitab(app.TabGroup);
            app.GierreaktionTab.Title = 'Gierreaktion';
            app.GierreaktionLabel = uilabel(app.GierreaktionTab);
            app.GierreaktionLabel.Text = 'Gierreaktion (Diagramm 4.8)';
            app.StatusGierreaktionLabel = uilabel(app.GierreaktionTab);
            app.StatusGierreaktionLabel.Text = 'Ready';
            app.GierreaktionAxes = uiaxes(app.GierreaktionTab);
            title(app.GierreaktionAxes, 'Gierreaktion')
            xlabel(app.GierreaktionAxes, 'Fahrzeuggeschwindigkeit v [m/s]')
            ylabel(app.GierreaktionAxes, 'Giergeschwindigkeit (durch Lenkradwinkel)')
            app.SimStartGierreaktionButton = uibutton(app.GierreaktionTab, 'push');
            app.SimStartGierreaktionButton.ButtonPushedFcn = createCallbackFcn(app, @SimStartGierreaktionButtonPushed, true);
            app.SimStartGierreaktionButton.Text = 'Simulation starten';
            app.ReifekennlinieTab = uitab(app.TabGroup);
            app.ReifekennlinieTab.Title = 'Reifekennlinie';
            app.ReifenkennlinieLabel = uilabel(app.ReifekennlinieTab);
            app.ReifenkennlinieLabel.Text = 'Reifenkennlinie';
            app.ReifenkennlinieAxes = uiaxes(app.ReifekennlinieTab);
            title(app.ReifenkennlinieAxes, 'Reifekennlinie')
            xlabel(app.ReifenkennlinieAxes, 'Längsschlupf λ')
            ylabel(app.ReifenkennlinieAxes, 'Reibungszahl μ')
            app.ReifenkennlinieDetailsLabel = uilabel(app.ReifekennlinieTab);
            app.ReifenkennlinieDetailsLabel.Text = '(nur für Bremsvorgang benötigt)';
            app.KennlinienartButtonGroup = uibuttongroup(app.ReifekennlinieTab);
            app.KennlinienartButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KennlinienartButtonGroupSelectionChanged, true);
            app.KennlinienartButtonGroup.Title = 'Kennlinienart';
            app.LinearButton = uiradiobutton(app.KennlinienartButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.AsphaltButton = uiradiobutton(app.KennlinienartButtonGroup);
            app.AsphaltButton.Text = 'Asphalt';
            app.KiesloseButton = uiradiobutton(app.KennlinienartButtonGroup);
            app.KiesloseButton.Text = 'Kies lose';
            app.EisButton = uiradiobutton(app.KennlinienartButtonGroup);
            app.EisButton.Text = 'Eis';
            app.ReibungmaxEditFieldLabel = uilabel(app.ReifekennlinieTab);
            app.ReibungmaxEditFieldLabel.Text = 'Reibung μ max:';
            app.ReibungmaxEditField = uieditfield(app.ReifekennlinieTab, 'numeric');
            app.ReibungmaxEditField.Limits = [0 Inf];
            app.ReibungmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ReibungmaxEditFieldValueChanged, true);
            app.ReibungmaxEditField.Value = 1;
            app.LngsschlupfmaxEditFieldLabel = uilabel(app.ReifekennlinieTab);
            app.LngsschlupfmaxEditFieldLabel.Text = 'Längsschlupf λ max:';
            app.LangsschlupfmaxEditField = uieditfield(app.ReifekennlinieTab, 'numeric');
            app.LangsschlupfmaxEditField.Limits = [0.2 0.6];
            app.LangsschlupfmaxEditField.ValueChangedFcn = createCallbackFcn(app, @LangsschlupfmaxEditFieldValueChanged, true);
            app.LangsschlupfmaxEditField.Value = 0.25;
            app.FahrzeugparameterTab = uitab(app.TabGroup);
            app.FahrzeugparameterTab.Title = 'Fahrzeugparameter';
            app.FahrzeugparameterLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugparameterLabel.Text = 'Fahrzeugparameter';
            app.FahrzeugparamDetailSLAbel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugparamDetailSLAbel.Text = '(für alle Diagramme gültig)';
            app.FahrzeuglngelmEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeuglngelmEditFieldLabel.Text = 'Fahrzeuglänge l [m]';
            app.FahrzeuglangeEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.FahrzeuglangeEditField.Limits = [0 Inf];
            app.FahrzeuglangeEditField.ValueChangedFcn = createCallbackFcn(app, @FahrzeuglangeEditFieldValueChanged, true);
            app.FahrzeuglangeEditField.Value = 2.8;
            app.VorderlngelvmLabel = uilabel(app.FahrzeugparameterTab);
            app.VorderlngelvmLabel.Text = 'Vorderlänge lv [m]';
            app.VorderlangeEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.VorderlangeEditField.Limits = [0 Inf];
            app.VorderlangeEditField.ValueChangedFcn = createCallbackFcn(app, @VorderlangeEditFieldValueChanged, true);
            app.VorderlangeEditField.Value = 1.35;
            app.HinterlngelhmEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.HinterlngelhmEditFieldLabel.Text = 'Hinterlänge lh [m]';
            app.HinterlangeEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.HinterlangeEditField.Limits = [0 Inf];
            app.HinterlangeEditField.ValueChangedFcn = createCallbackFcn(app, @HinterlangeEditFieldValueChanged, true);
            app.HinterlangeEditField.Value = 1.45;
            app.VerhltnislvlLabel = uilabel(app.FahrzeugparameterTab);
            app.VerhltnislvlLabel.Text = 'Verhältnis [lv/l]';
            app.VerhaltnisVEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.VerhaltnisVEditField.Limits = [0 1];
            app.VerhaltnisVEditField.ValueChangedFcn = createCallbackFcn(app, @VerhaltnisVEditFieldValueChanged, true);
            app.VerhaltnisVEditField.Editable = 'off';
            app.VerhaltnisVEditField.Value = 0.48;
            app.VerhltnislhlLabel = uilabel(app.FahrzeugparameterTab);
            app.VerhltnislhlLabel.Text = 'Verhältnis [lh/l]';
            app.VerhaltnisHEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.VerhaltnisHEditField.Limits = [0 1];
            app.VerhaltnisHEditField.ValueChangedFcn = createCallbackFcn(app, @VerhaltnisHEditFieldValueChanged, true);
            app.VerhaltnisHEditField.Editable = 'off';
            app.VerhaltnisHEditField.Value = 0.52;
            app.FahrzeugmassemkgEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugmassemkgEditFieldLabel.Text = 'Fahrzeugmasse m [kg]';
            app.FahrzeugmasseEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.FahrzeugmasseEditField.Limits = [0 Inf];
            app.FahrzeugmasseEditField.ValueChangedFcn = createCallbackFcn(app, @FahrzeugmasseEditFieldValueChanged, true);
            app.FahrzeugmasseEditField.Value = 1550;
            app.ThetakgmEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.ThetakgmEditFieldLabel.Text = 'Theta [kg*m²]';
            app.ThetaEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ThetaEditField.Value = 2800;
            app.ErdbeschleunigunggmsEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.ErdbeschleunigunggmsEditFieldLabel.Text = 'Erdbeschleunigung g [m/s²]';
            app.ErdbeschleunigungEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ErdbeschleunigungEditField.Limits = [0 Inf];
            app.ErdbeschleunigungEditField.ValueChangedFcn = createCallbackFcn(app, @ErdbeschleunigungEditFieldValueChanged, true);
            app.ErdbeschleunigungEditField.Value = 9.81;
            app.Cv1radLabel = uilabel(app.FahrzeugparameterTab);
            app.Cv1radLabel.Text = 'Cv* [1/rad]';
            app.CvSternEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.CvSternEditField.ValueChangedFcn = createCallbackFcn(app, @CvSternEditFieldValueChanged, true);
            app.Ch1radLabel = uilabel(app.FahrzeugparameterTab);
            app.Ch1radLabel.Text = 'Ch* [1/rad]';
            app.ChSternEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ChSternEditField.ValueChangedFcn = createCallbackFcn(app, @ChSternEditFieldValueChanged, true);
            app.CvNradEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.CvNradEditFieldLabel.Text = 'Cv [N/rad]';
            app.CvEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.CvEditField.ValueChangedFcn = createCallbackFcn(app, @CvEditFieldValueChanged, true);
            app.CvEditField.Value = 75000;
            app.ChNradLabel = uilabel(app.FahrzeugparameterTab);
            app.ChNradLabel.Text = 'Ch [N/rad]';
            app.ChEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.ChEditField.ValueChangedFcn = createCallbackFcn(app, @ChEditFieldValueChanged, true);
            app.ChEditField.Value = 150000;
            app.SGradsmEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.SGradsmEditFieldLabel.Text = 'SG [rad*s²/m]';
            app.SGEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.SGEditField.ValueChangedFcn = createCallbackFcn(app, @SGEditFieldValueChanged, true);
            app.EGradsmEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.EGradsmEditFieldLabel.Text = 'EG [rad*s²/m]';
            app.EGEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.EGEditField.ValueChangedFcn = createCallbackFcn(app, @EGEditFieldValueChanged, true);
            app.hsmEditFieldLabel = uilabel(app.FahrzeugparameterTab);
            app.hsmEditFieldLabel.Text = 'hs [m]';
            app.hsEditField = uieditfield(app.FahrzeugparameterTab, 'numeric');
            app.hsEditField.ValueChangedFcn = createCallbackFcn(app, @hsEditFieldValueChanged, true);
            app.hsEditField.Value = 0.5;
            app.FahrzeugmaseLabel = uilabel(app.FahrzeugparameterTab);
            app.FahrzeugmaseLabel.Text = 'Fahrzeugmaße';
            app.VerhaltenLabel = uilabel(app.FahrzeugparameterTab);
            app.VerhaltenLabel.Text = 'Verhalten';
            app.AbhngigeGrenwerdenautomatischberechnetLabel = uilabel(app.FahrzeugparameterTab);
            app.AbhngigeGrenwerdenautomatischberechnetLabel.Text = 'Abhängige Größen werden automatisch berechnet';
            app.errorLabel = uilabel(app.FahrzeugparameterTab);
            app.errorLabel.Text = 'error';
            app.EinspurmodellLabel = uilabel(app.EinspurmodellUIFigure);
            app.EinspurmodellLabel.Text = 'Einspurmodell';
            app.STGTINF16ITALabel = uilabel(app.EinspurmodellUIFigure);
            app.STGTINF16ITALabel.Text = 'STG-TINF16ITA';
            app.NamesLabel = uilabel(app.EinspurmodellUIFigure);
            app.NamesLabel.Text = {'Melanie Schneider'; 'Felix Heuser'};
            % Show the figure after all components are created
            app.EinspurmodellUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EinspurmodellUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EinspurmodellUIFigure)
        end
    end
end